classdef ComponentSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectandenteryourdesiredcomponentsindexesLabel  matlab.ui.control.Label
        HeartcomponentsEditFieldLabel  matlab.ui.control.Label
        HeartcomponentsEditField       matlab.ui.control.EditField
        DoneButton                     matlab.ui.control.Button
        ListentothecomponentnoEditFieldLabel  matlab.ui.control.Label
        ListentothecomponentnoEditField  matlab.ui.control.NumericEditField
        PlayButton                     matlab.ui.control.Button
        LungcomponentsEditFieldLabel   matlab.ui.control.Label
        LungcomponentsEditField        matlab.ui.control.EditField
        SignalNameLabel                matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Y % Description
        fs
        ph
        pl
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Y=evalin('base','Y');
            app.fs=evalin('base','fs');
            app.ph=evalin('base','ph');
            app.pl=evalin('base','pl');
            app.HeartcomponentsEditField.Value=num2str(app.ph);
            app.LungcomponentsEditField.Value=num2str(app.pl);
            app.SignalNameLabel.Text=evalin('base','files(i).name');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            eval(['app.ph=[' app.HeartcomponentsEditField.Value ']']);
            eval(['app.pl=[' app.LungcomponentsEditField.Value ']']);

            assignin("base", 'ph',app.ph );
            assignin("base", 'pl',app.pl);
            UIFigureCloseRequest(app,event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            y=normalize(real(app.Y(app.ListentothecomponentnoEditField.Value,:)))./10;
            sound(y,app.fs+1,16)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            assignin('base','cnfrm',false)
            assignin('base','Save',false)
            UIFigureCloseRequest(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectandenteryourdesiredcomponentsindexesLabel = uilabel(app.UIFigure);
            app.SelectandenteryourdesiredcomponentsindexesLabel.Text = 'Select and enter your desired components indexes:';
            app.HeartcomponentsEditFieldLabel = uilabel(app.UIFigure);
            app.HeartcomponentsEditFieldLabel.Text = 'Heart components';
            app.HeartcomponentsEditField = uieditfield(app.UIFigure, 'text');
            app.HeartcomponentsEditField.Value = '1,2,3';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ListentothecomponentnoEditFieldLabel = uilabel(app.UIFigure);
            app.ListentothecomponentnoEditFieldLabel.Text = 'Listen to the component no.';
            app.ListentothecomponentnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ListentothecomponentnoEditField.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.LungcomponentsEditFieldLabel = uilabel(app.UIFigure);
            app.LungcomponentsEditFieldLabel.Text = 'Lung components';
            app.LungcomponentsEditField = uieditfield(app.UIFigure, 'text');
            app.LungcomponentsEditField.Value = '4,5,6';
            app.SignalNameLabel = uilabel(app.UIFigure);
            app.SignalNameLabel.Text = 'Signal Name';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComponentSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end