classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveandcontinueButton        matlab.ui.control.Button
        ContinuewithoutsavingButton  matlab.ui.control.Button
        RepeatwithoutsavingButton    matlab.ui.control.Button
        SaveandRepeatButton          matlab.ui.control.Button
        NextStepLabel                matlab.ui.control.Label
        PlayheartsoundButton         matlab.ui.control.Button
        PlaylungsoundButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        H % Description
        L
        fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.H=evalin('base','H');
            app.L=evalin('base','L');
            app.fs=evalin('base','fs');            
        end

        % Button pushed function: SaveandcontinueButton
        function SaveandcontinueButtonPushed(app, event)
            assignin('base','Save',true)
            assignin('base','Repeat',false)
            UIFigureCloseRequest(app, event)            
        end

        % Button pushed function: ContinuewithoutsavingButton
        function ContinuewithoutsavingButtonPushed(app, event)
            assignin('base','Save',false)
            assignin('base','Repeat',false)
            UIFigureCloseRequest(app, event)               
        end

        % Button pushed function: RepeatwithoutsavingButton
        function RepeatwithoutsavingButtonPushed(app, event)
            assignin('base','Save',false)
            assignin('base','Repeat',true)
            UIFigureCloseRequest(app, event)               
        end

        % Button pushed function: SaveandRepeatButton
        function SaveandRepeatButtonPushed(app, event)
            assignin('base','Save',true)
            assignin('base','Repeat',true)
            UIFigureCloseRequest(app, event)               
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: PlayheartsoundButton
        function PlayheartsoundButtonPushed(app, event)
            sound(normalize(app.H)./5,app.fs+1,16)          
        end

        % Button pushed function: PlaylungsoundButton
        function PlaylungsoundButtonPushed(app, event)
            sound(normalize(app.L)./5,app.fs+1,16)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.SaveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcontinueButtonPushed, true);
            app.SaveandcontinueButton.Text = 'Save and continue';
            app.ContinuewithoutsavingButton = uibutton(app.UIFigure, 'push');
            app.ContinuewithoutsavingButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuewithoutsavingButtonPushed, true);
            app.ContinuewithoutsavingButton.Text = 'Continue without saving';
            app.RepeatwithoutsavingButton = uibutton(app.UIFigure, 'push');
            app.RepeatwithoutsavingButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatwithoutsavingButtonPushed, true);
            app.RepeatwithoutsavingButton.Text = 'Repeat without saving';
            app.SaveandRepeatButton = uibutton(app.UIFigure, 'push');
            app.SaveandRepeatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandRepeatButtonPushed, true);
            app.SaveandRepeatButton.Text = 'Save and Repeat';
            app.NextStepLabel = uilabel(app.UIFigure);
            app.NextStepLabel.Text = 'Next Step';
            app.PlayheartsoundButton = uibutton(app.UIFigure, 'push');
            app.PlayheartsoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayheartsoundButtonPushed, true);
            app.PlayheartsoundButton.Text = 'Play heart sound';
            app.PlaylungsoundButton = uibutton(app.UIFigure, 'push');
            app.PlaylungsoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaylungsoundButtonPushed, true);
            app.PlaylungsoundButton.Text = 'Play lung sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end