classdef ParameterSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PleasesetthefollowingparametersvaluesandthenpressDoneLabel  matlab.ui.control.Label
        VoiceperoctaveinCWTEditFieldLabel  matlab.ui.control.Label
        VoiceperoctaveinCWTEditField   matlab.ui.control.NumericEditField
        DoneButton                     matlab.ui.control.Button
        WaveletnameEditFieldLabel      matlab.ui.control.Label
        WaveletnameEditField           matlab.ui.control.EditField
        BoundsoffrequencyrangeHzLabel  matlab.ui.control.Label
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.NumericEditField
        UpperEditFieldLabel            matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.NumericEditField
        AverageheartrateforPiCAEditFieldLabel  matlab.ui.control.Label
        AverageheartrateforPiCAEditField  matlab.ui.control.NumericEditField
        Approximatefrequencyofthesubbandutilizedfors1detectionHzLabel  matlab.ui.control.Label
        fpdEditField                   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VoiceperoctaveinCWTEditField.Value=evalin('base','vpo');
            app.WaveletnameEditField.Value=evalin('base','wname');
            app.LowerEditField.Value=evalin('base','fl');
            app.UpperEditField.Value=evalin('base','fh');
            app.AverageheartrateforPiCAEditField.Value=evalin('base','AHR');
            app.fpdEditField.Value=evalin('base','fpd');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base','vpo',app.VoiceperoctaveinCWTEditField.Value);
            assignin('base','fl',app.LowerEditField.Value);
            assignin('base','fh',app.UpperEditField.Value);
            assignin('base','wname',app.WaveletnameEditField.Value);
            assignin('base','AHR',app.AverageheartrateforPiCAEditField.Value);
            assignin('base','fpd',app.fpdEditField.Value);
            UIFigureCloseRequest(app, event)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PleasesetthefollowingparametersvaluesandthenpressDoneLabel = uilabel(app.UIFigure);
            app.PleasesetthefollowingparametersvaluesandthenpressDoneLabel.Text = 'Please set the following parameters'' values and then press Done. ';
            app.VoiceperoctaveinCWTEditFieldLabel = uilabel(app.UIFigure);
            app.VoiceperoctaveinCWTEditFieldLabel.Text = 'Voice per octave in CWT';
            app.VoiceperoctaveinCWTEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.WaveletnameEditFieldLabel = uilabel(app.UIFigure);
            app.WaveletnameEditFieldLabel.Text = 'Wavelet name';
            app.WaveletnameEditField = uieditfield(app.UIFigure, 'text');
            app.BoundsoffrequencyrangeHzLabel = uilabel(app.UIFigure);
            app.BoundsoffrequencyrangeHzLabel.Text = 'Bounds of frequency range (Hz):';
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperEditFieldLabel = uilabel(app.UIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageheartrateforPiCAEditFieldLabel = uilabel(app.UIFigure);
            app.AverageheartrateforPiCAEditFieldLabel.Text = 'Average heart rate for PiCA';
            app.AverageheartrateforPiCAEditField = uieditfield(app.UIFigure, 'numeric');
            app.Approximatefrequencyofthesubbandutilizedfors1detectionHzLabel = uilabel(app.UIFigure);
            app.Approximatefrequencyofthesubbandutilizedfors1detectionHzLabel.Text = 'Approximate frequency of the subband utilized for s1 detection (Hz):';
            app.fpdEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterSetting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end