classdef InputSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowseButton_1      matlab.ui.control.Button
        LoadButton          matlab.ui.control.Button
        AddressEditField_1  matlab.ui.control.EditField
        AddressEditField_2  matlab.ui.control.EditField
        SinglefileCheckBox  matlab.ui.control.CheckBox
        AlltheAudiofilesinthefollowingaddressCheckBox  matlab.ui.control.CheckBox
        BrowseButton_2      matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileaddress % Description
        filename1
        filepath1
        filepath2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SinglefileCheckBox.Value=true;
            SinglefileCheckBoxValueChanged(app)
            
        end

        % Button pushed function: BrowseButton_1
        function BrowseButton_1Pushed(app, event)
            [app.filename1,app.filepath1]=uigetfile({'*.wav; *.mp3','Audio files'},'Select sound file');
            app.fileaddress=[app.filepath1 '/' app.filename1];
            app.AddressEditField_1.Value= app.fileaddress;
            drawnow
            figure(app.UIFigure)
        end

        % Value changed function: AddressEditField_1
        function AddressEditField_1ValueChanged(app, event)
            app.fileaddress = app.AddressEditField_1.Value;

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.filepath2=uigetdir(' ','Select the folder');
            app.AddressEditField_2.Value= app.filepath2;
            drawnow
            figure(app.UIFigure)
        end

        % Value changed function: AddressEditField_2
        function AddressEditField_2ValueChanged(app, event)
            app.filepath2 = app.AddressEditField_2.Value;
            
        end

        % Value changed function: SinglefileCheckBox
        function SinglefileCheckBoxValueChanged(app, event)
            app.AlltheAudiofilesinthefollowingaddressCheckBox.Value = ~ app.SinglefileCheckBox.Value;
            app.AddressEditField_1.Enable= app.SinglefileCheckBox.Value;
            app.AddressEditField_2.Enable= ~ app.SinglefileCheckBox.Value;
            app.BrowseButton_1.Enable = app.SinglefileCheckBox.Value;
            app.BrowseButton_2.Enable = ~ app.SinglefileCheckBox.Value;
            
        end

        % Value changed function: 
        % AlltheAudiofilesinthefollowingaddressCheckBox
        function AlltheAudiofilesinthefollowingaddressCheckBoxValueChanged(app, event)
            app.SinglefileCheckBox.Value = ~ app.AlltheAudiofilesinthefollowingaddressCheckBox.Value;
            app.AddressEditField_1.Enable= ~ app.AlltheAudiofilesinthefollowingaddressCheckBox.Value;
            app.AddressEditField_2.Enable= app.AlltheAudiofilesinthefollowingaddressCheckBox.Value;
            app.BrowseButton_1.Enable = ~ app.AlltheAudiofilesinthefollowingaddressCheckBox.Value;
            app.BrowseButton_2.Enable = app.AlltheAudiofilesinthefollowingaddressCheckBox.Value;

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.SinglefileCheckBox.Value
                assignin('base','DA',app.filepath1);
                assignin('base','FileName',app.filename1);
                assignin('base','InputFormat','single');
            elseif app.AlltheAudiofilesinthefollowingaddressCheckBox.Value
                assignin('base','DA',app.AddressEditField_2.Value);
                assignin('base','InputFormat','all');                
            end
            UIFigureCloseRequest(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BrowseButton_1 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_1.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_1Pushed, true);
            app.BrowseButton_1.Text = 'Browse';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddressEditField_1 = uieditfield(app.UIFigure, 'text');
            app.AddressEditField_1.ValueChangedFcn = createCallbackFcn(app, @AddressEditField_1ValueChanged, true);
            app.AddressEditField_2 = uieditfield(app.UIFigure, 'text');
            app.AddressEditField_2.ValueChangedFcn = createCallbackFcn(app, @AddressEditField_2ValueChanged, true);
            app.SinglefileCheckBox = uicheckbox(app.UIFigure);
            app.SinglefileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SinglefileCheckBoxValueChanged, true);
            app.SinglefileCheckBox.Text = 'Single file';
            app.AlltheAudiofilesinthefollowingaddressCheckBox = uicheckbox(app.UIFigure);
            app.AlltheAudiofilesinthefollowingaddressCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlltheAudiofilesinthefollowingaddressCheckBoxValueChanged, true);
            app.AlltheAudiofilesinthefollowingaddressCheckBox.Text = 'All the Audio files in the following address:';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputSignals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end