classdef ParameterSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PleasesetthefollowingparametersvaluesandthenpressDoneLabel  matlab.ui.control.Label
        NumberoflevelsinSWTEditFieldLabel  matlab.ui.control.Label
        NumberoflevelsinSWTEditField  matlab.ui.control.NumericEditField
        DoneButton                    matlab.ui.control.Button
        WaveletnameEditFieldLabel     matlab.ui.control.Label
        WaveletnameEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberoflevelsinSWTEditField.Value=evalin('base','N');
            app.WaveletnameEditField.Value=evalin('base','wname');
            

        end

        % Value changed function: NumberoflevelsinSWTEditField
        function NumberoflevelsinSWTEditFieldValueChanged(app, event)
            value = app.NumberoflevelsinSWTEditField.Value;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base','N',app.NumberoflevelsinSWTEditField.Value);
            assignin('base','wname',app.WaveletnameEditField.Value);
            UIFigureCloseRequest(app, event)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: WaveletnameEditField
        function WaveletnameEditFieldValueChanged(app, event)
            value = app.WaveletnameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PleasesetthefollowingparametersvaluesandthenpressDoneLabel = uilabel(app.UIFigure);
            app.PleasesetthefollowingparametersvaluesandthenpressDoneLabel.Text = 'Please set the following parameters'' values and then press Done. ';
            app.NumberoflevelsinSWTEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflevelsinSWTEditFieldLabel.Text = 'Number of levels in SWT';
            app.NumberoflevelsinSWTEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflevelsinSWTEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflevelsinSWTEditFieldValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.WaveletnameEditFieldLabel = uilabel(app.UIFigure);
            app.WaveletnameEditFieldLabel.Text = 'Wavelet name';
            app.WaveletnameEditField = uieditfield(app.UIFigure, 'text');
            app.WaveletnameEditField.ValueChangedFcn = createCallbackFcn(app, @WaveletnameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterSetting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end